classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        FillSliderLabel           matlab.ui.control.Label
        FillSlider                matlab.ui.control.Slider
        NewSeedButton             matlab.ui.control.Button
        ViewDropDownLabel         matlab.ui.control.Label
        ViewDropDown              matlab.ui.control.DropDown
        MinXEditFieldLabel        matlab.ui.control.Label
        MinXEditField             matlab.ui.control.NumericEditField
        MinYEditFieldLabel        matlab.ui.control.Label
        MinYEditField             matlab.ui.control.NumericEditField
        MinZEditFieldLabel        matlab.ui.control.Label
        MinZEditField             matlab.ui.control.NumericEditField
        MaxXEditFieldLabel        matlab.ui.control.Label
        MaxXEditField             matlab.ui.control.NumericEditField
        MaxYEditFieldLabel        matlab.ui.control.Label
        MaxYEditField             matlab.ui.control.NumericEditField
        MaxZEditFieldLabel        matlab.ui.control.Label
        MaxZEditField             matlab.ui.control.NumericEditField
        GridSizeSpinnerLabel      matlab.ui.control.Label
        GridSizeSpinner           matlab.ui.control.Spinner
        HarmonicsSpinnerLabel     matlab.ui.control.Label
        HarmonicsSpinner          matlab.ui.control.Spinner
        RoughnessSpinnerLabel     matlab.ui.control.Label
        RoughnessSpinner          matlab.ui.control.Spinner
        LevelCheckBox             matlab.ui.control.CheckBox
        ResolutionEditFieldLabel  matlab.ui.control.Label
        ResolutionEditField       matlab.ui.control.NumericEditField
        ShowDitherCheckBox        matlab.ui.control.CheckBox
        DitherLabel               matlab.ui.control.Label
        ExportButton              matlab.ui.control.Button
        NoiseSetDropDownLabel     matlab.ui.control.Label
        NoiseSetDropDown          matlab.ui.control.DropDown
    end


    properties (Access = private)
        ignore_redraw = false;
        oct_seed % Octave Noise Seed
        elepos % Atrial volume element centroids
        dither = struct('unit', 0.05, 'harmonics', 3, 'roughness', 0.9, 'fill', 0.5);
        region;
    end

    methods (Access = private)
        
        function box = get_box(app)
            box = [
                app.MinXEditField.Value, app.MinYEditField.Value, app.MinZEditField.Value;
                app.MaxXEditField.Value, app.MaxYEditField.Value, app.MaxZEditField.Value
            ];
        end
        
        function set_box(app, box)
            app.MinXEditField.Value = box(1, 1);
            app.MinYEditField.Value = box(1, 2);
            app.MinZEditField.Value = box(1, 3);
            app.MaxXEditField.Value = box(2, 1);
            app.MaxYEditField.Value = box(2, 2);
            app.MaxZEditField.Value = box(2, 3);
        end
        
        function [pts, shape] = get_points(app)
            if strcmp(app.ViewDropDown.Value, 'Atria')
                pts = app.elepos(1:app.ResolutionEditField.Value:end, :);
                shape = size(pts, 1);
            else
                lx = linspace(app.MinXEditField.Value, app.MaxXEditField.Value, app.ResolutionEditField.Value);
                ly = linspace(app.MinYEditField.Value, app.MaxYEditField.Value, app.ResolutionEditField.Value);
                lz = linspace(app.MinZEditField.Value, app.MaxZEditField.Value, app.ResolutionEditField.Value);
                
                if ~strcmp(app.ViewDropDown.Value, '3D')
                    lz = 0;
                end
                [X, Y, Z] = meshgrid(lx, ly, lz);
                shape = size(X);
                pts = [X(:) Y(:) Z(:)];
            end
        end
        
        function noisecfg = get_noise_config(app)
            noisecfg = struct('unit', app.GridSizeSpinner.Value, 'harmonics', app.HarmonicsSpinner.Value, ...
                'roughness', app.RoughnessSpinner.Value, 'fill', app.FillSlider.Value / 100);
            if ~app.LevelCheckBox.Value
                noisecfg.fill = 0;
            end
        end
        
        function set_noise_config(app, noisecfg)
            app.GridSizeSpinner.Value = noisecfg.unit;
            app.HarmonicsSpinner.Value = noisecfg.harmonics;
            app.RoughnessSpinner.Value = noisecfg.roughness;
            app.FillSlider.Value = noisecfg.fill * 100;
            app.LevelCheckBox.Value = noisecfg.fill > 0;
            app.FillSlider.Enable = app.onoff(app.LevelCheckBox.Value);
        end
        
        function redraw(app, force)
            if app.ignore_redraw && (nargin == 1 || ~force)
                return
            end
            app.ignore_redraw = false;
            
            title(app.UIAxes, 'Loading...');
            drawnow;
            
            [pts, shape] = app.get_points();
            noisecfg = app.get_noise_config();
            
            table = app.oct_seed(1:noisecfg.harmonics);
            baseFactor = 1 / noisecfg.unit;
            twoD = min(pts(:, 3)) == max(pts(:, 3));
            if strcmp(app.ViewDropDown.Value, 'Perlin')
                baseFactor = baseFactor * 2^(noisecfg.harmonics-1);
                table = table(end);
            end
            noise = octave(pts * baseFactor, table, noisecfg.roughness, twoD);
            if length(shape) > 1
                noise = reshape(noise, shape);
            end
            if app.LevelCheckBox.Value
                f = find_level(noise, noisecfg.fill);
                if ~strcmp(app.ViewDropDown.Value, '3D')
                    noise = noise <= f;
                    
                    if strcmp(app.NoiseSetDropDown.Value, 'Region') && app.ShowDitherCheckBox.Value
                        noise2 = apply_field(pts, app.oct_seed, app.dither);
                        if length(shape) > 1
                            noise2 = reshape(noise2, shape);
                        end
                       noise = noise & noise2;
                    end
                end
            end
            
            if twoD
                xlim(app.UIAxes, [0 shape(1)]);
                ylim(app.UIAxes, [0 shape(2)]);
                imagesc(app.UIAxes, noise);
            else
                [az,el] = view(app.UIAxes);
                if strcmp(app.ViewDropDown.Value, 'Atria')
                    scatter3(app.UIAxes, pts(:, 1), pts(:, 2), pts(:, 3), 3, noise);
                else
                    fv = isosurface(reshape(pts(:, 1), shape), reshape(pts(:, 2), shape), reshape(pts(:, 3), shape), noise, f);
                    cla(app.UIAxes);
                    p = patch(app.UIAxes, fv, 'FaceColor', 'flat', 'FaceLighting', 'gouraud', 'EdgeColor', 'none');
                    set(p, 'FaceVertexCData', [0, 0, 1]);
                    camlight(app.UIAxes);
                end
                if el < 90
                    view(app.UIAxes, az,el);
                else
                    view(app.UIAxes, 3);
                end
            end
            title(app.UIAxes, app.ViewDropDown.Value);
            colorbar(app.UIAxes);
        end
    end
    
    methods (Static)
        function str = onoff(bool)
            if bool
                str = 'on';
            else
                str = 'off';
            end
        end
        
        function str = noise2string(noise)
            str = sprintf('x%.3f @ n=%d, r=%.2f, f=%.2f', noise.unit, noise.harmonics, noise.roughness, noise.fill);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'))
            
            nodes = LoadNodeCoords;
            ele = LoadEleIndices;
            app.elepos = mean(permute(reshape(nodes(ele, :)', 3, [], size(ele, 2)), [2 1 3]), 3);
            app.set_box([min(nodes); max(nodes)]);
            
            cla(app.UIAxes, 'reset');
            rotate3d(app.UIAxes,'on');
            
            app.ignore_redraw = true;
            app.NoiseSetDropDownValueChanged(struct('Value', 'Region', 'PreviousValue', 'Region'));
            app.NewSeedButtonPushed(0);
            app.redraw(true);
        end

        % Button pushed function: NewSeedButton
        function NewSeedButtonPushed(app, event)
            app.oct_seed = seed_octave(app.HarmonicsSpinner.Limits(2));
            app.redraw();
        end

        % Value changed function: MaxXEditField, MaxYEditField, 
        % MaxZEditField, MinXEditField, MinYEditField, MinZEditField
        function BoxEditFieldValueChanged(app, event)
            switch event.Source
                case app.MinXEditField
                    if (app.MinXEditField.Value > app.MaxXEditField.Value)
                        app.MinXEditField.Value = app.MaxXEditField.Value;
                    end
                case app.MinYEditField
                    if (app.MinYEditField.Value > app.MaxYEditField.Value)
                        app.MinYEditField.Value = app.MaxYEditField.Value;
                    end
                case app.MinZEditField
                    if (app.MinZEditField.Value > app.MaxZEditField.Value)
                        app.MinZEditField.Value = app.MaxZEditField.Value;
                    end
                case app.MaxXEditField
                    if (app.MaxXEditField.Value < app.MinXEditField.Value)
                        app.MaxXEditField.Value = app.MinXEditField.Value;
                    end
                case app.MaxYEditField
                    if (app.MaxYEditField.Value < app.MinYEditField.Value)
                        app.MaxYEditField.Value = app.MinYEditField.Value;
                    end
                case app.MaxZEditField
                    if (app.MaxZEditField.Value < app.MinZEditField.Value)
                        app.MaxZEditField.Value = app.MinZEditField.Value;
                    end
            end
            app.redraw();
        end

        % Value changed function: FillSlider, GridSizeSpinner, 
        % HarmonicsSpinner, ResolutionEditField, RoughnessSpinner, 
        % ShowDitherCheckBox
        function RedrawAppOnChange(app, event)
            app.redraw();
        end

        % Value changed function: LevelCheckBox
        function LevelCheckBoxValueChanged(app, event)
            app.FillSlider.Enable = app.onoff(app.LevelCheckBox.Value);
            app.redraw();
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            switch app.ViewDropDown.Value
                case 'Atria'
                    app.ResolutionEditField.Limits(2) = 100;
                    app.ResolutionEditField.Value = 25;
                case '3D'
                    app.ResolutionEditField.Limits(2) = 127;
                    app.ResolutionEditField.Value = 65;
                otherwise
                    app.ResolutionEditField.Limits(2) = 1025;
                    app.ResolutionEditField.Value = 257;
            end
            app.LevelCheckBox.Enable = app.onoff(~strcmp(app.ViewDropDown.Value, '3D'));
            if strcmp(app.ViewDropDown.Value, '3D')
                app.LevelCheckBox.Value = 1;
                app.FillSlider.Enable = 'on';
            end
            app.ExportButton.Visible = app.onoff(strcmp(app.ViewDropDown.Value, 'Atria'));
            cla(app.UIAxes, 'reset');
            app.redraw();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.ignore_redraw = true;
            app.NoiseSetDropDownValueChanged(struct('Value', app.NoiseSetDropDown.Value, 'PreviousValue', app.NoiseSetDropDown.Value));
            app.ignore_redraw = false;
            
            pattern = struct('oct_seed', app.oct_seed, 'region', app.region);
            if app.ShowDitherCheckBox.Value
                pattern.('dither') = app.dither;
            end
            assignin('base', 'fibrotic_pattern', pattern);
            msgbox('Saved to workspace variable "fibrotic_pattern"', 'Exported');
        end

        % Value changed function: NoiseSetDropDown
        function NoiseSetDropDownValueChanged(app, event)
            if strcmp(event.PreviousValue, 'Region')
                app.region = app.get_noise_config();
            else
                app.dither = app.get_noise_config();
            end
            if strcmp(event.Value, 'Region')
                app.set_noise_config(app.region);
                app.DitherLabel.Text = strcat("Dither ", app.noise2string(app.dither));
                app.ShowDitherCheckBox.Visible = 'on';
                app.ExportButton.Visible = 'on';
            else
                app.set_noise_config(app.dither);
                app.DitherLabel.Text = strcat("Region ", app.noise2string(app.region));
                app.ShowDitherCheckBox.Visible = 'off';
                app.ExportButton.Visible = 'off';
            end
            app.redraw();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.FillSliderLabel = uilabel(app.UIFigure);
            app.FillSliderLabel.Enable = 'off';
            app.FillSliderLabel.Text = 'Fill %';
            app.FillSlider = uislider(app.UIFigure);
            app.FillSlider.ValueChangedFcn = createCallbackFcn(app, @RedrawAppOnChange, true);
            app.FillSlider.Enable = 'off';
            app.FillSlider.Value = 40;
            app.NewSeedButton = uibutton(app.UIFigure, 'push');
            app.NewSeedButton.ButtonPushedFcn = createCallbackFcn(app, @NewSeedButtonPushed, true);
            app.NewSeedButton.Text = 'New Seed';
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'Atria', 'Octave', 'Perlin', '3D'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Atria';
            app.MinXEditFieldLabel = uilabel(app.UIFigure);
            app.MinXEditFieldLabel.Text = 'Min X';
            app.MinXEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinXEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.MinYEditFieldLabel = uilabel(app.UIFigure);
            app.MinYEditFieldLabel.Text = 'Min Y';
            app.MinYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinYEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.MinZEditFieldLabel = uilabel(app.UIFigure);
            app.MinZEditFieldLabel.Text = 'Min Z';
            app.MinZEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinZEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.MaxXEditFieldLabel = uilabel(app.UIFigure);
            app.MaxXEditFieldLabel.Text = 'Max X';
            app.MaxXEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxXEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.MaxXEditField.Value = 16;
            app.MaxYEditFieldLabel = uilabel(app.UIFigure);
            app.MaxYEditFieldLabel.Text = 'Max Y';
            app.MaxYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxYEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.MaxYEditField.Value = 16;
            app.MaxZEditFieldLabel = uilabel(app.UIFigure);
            app.MaxZEditFieldLabel.Text = 'Max Z';
            app.MaxZEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxZEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.MaxZEditField.Value = 16;
            app.GridSizeSpinnerLabel = uilabel(app.UIFigure);
            app.GridSizeSpinnerLabel.Text = 'Grid Size';
            app.GridSizeSpinner = uispinner(app.UIFigure);
            app.GridSizeSpinner.LowerLimitInclusive = 'off';
            app.GridSizeSpinner.Limits = [0 Inf];
            app.GridSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @RedrawAppOnChange, true);
            app.GridSizeSpinner.Value = 1;
            app.HarmonicsSpinnerLabel = uilabel(app.UIFigure);
            app.HarmonicsSpinnerLabel.Text = 'Harmonics';
            app.HarmonicsSpinner = uispinner(app.UIFigure);
            app.HarmonicsSpinner.Limits = [1 6];
            app.HarmonicsSpinner.RoundFractionalValues = 'on';
            app.HarmonicsSpinner.ValueDisplayFormat = '%.0f';
            app.HarmonicsSpinner.ValueChangedFcn = createCallbackFcn(app, @RedrawAppOnChange, true);
            app.HarmonicsSpinner.Value = 3;
            app.RoughnessSpinnerLabel = uilabel(app.UIFigure);
            app.RoughnessSpinnerLabel.Text = 'Roughness';
            app.RoughnessSpinner = uispinner(app.UIFigure);
            app.RoughnessSpinner.Step = 0.1;
            app.RoughnessSpinner.LowerLimitInclusive = 'off';
            app.RoughnessSpinner.Limits = [0 1];
            app.RoughnessSpinner.ValueDisplayFormat = '%.2f';
            app.RoughnessSpinner.ValueChangedFcn = createCallbackFcn(app, @RedrawAppOnChange, true);
            app.RoughnessSpinner.Value = 0.5;
            app.LevelCheckBox = uicheckbox(app.UIFigure);
            app.LevelCheckBox.ValueChangedFcn = createCallbackFcn(app, @LevelCheckBoxValueChanged, true);
            app.LevelCheckBox.Text = 'Level';
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.Limits = [1 1025];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @RedrawAppOnChange, true);
            app.ResolutionEditField.Value = 25;
            app.ShowDitherCheckBox = uicheckbox(app.UIFigure);
            app.ShowDitherCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedrawAppOnChange, true);
            app.ShowDitherCheckBox.Text = 'Show Dither';
            app.DitherLabel = uilabel(app.UIFigure);
            app.DitherLabel.Text = 'Dither';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.NoiseSetDropDownLabel = uilabel(app.UIFigure);
            app.NoiseSetDropDownLabel.Text = 'Noise Set';
            app.NoiseSetDropDown = uidropdown(app.UIFigure);
            app.NoiseSetDropDown.Items = {'Region', 'Dither'};
            app.NoiseSetDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseSetDropDownValueChanged, true);
            app.NoiseSetDropDown.Value = 'Region';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end